classdef app_tucil2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TransformasiRanahSpasialLabel  matlab.ui.control.Label
        TransformasiRanahFrekuensiLabel  matlab.ui.control.Label
        JenisPassingListBox            matlab.ui.control.ListBox
        JenisPassingListBoxLabel       matlab.ui.control.Label
        JenisFilterListBox_2           matlab.ui.control.ListBox
        JenisFilterListBox_2Label      matlab.ui.control.Label
        JenisRanahListBox              matlab.ui.control.ListBox
        JenisRanahListBoxLabel         matlab.ui.control.Label
        JenisFilterListBox             matlab.ui.control.ListBox
        JenisFilterListBoxLabel        matlab.ui.control.Label
        ndigunakanhanyapadaBLPFLabel   matlab.ui.control.Label
        inputnEditField                matlab.ui.control.NumericEditField
        inputnEditFieldLabel           matlab.ui.control.Label
        inputD0EditField               matlab.ui.control.NumericEditField
        inputD0EditFieldLabel          matlab.ui.control.Label
        nuntukFilterEditField          matlab.ui.control.NumericEditField
        nuntukFilterEditFieldLabel     matlab.ui.control.Label
        MedianFilterdenganLabel        matlab.ui.control.Label
        penambahanSaltPepperLabel      matlab.ui.control.Label
        inputfilenameEditField         matlab.ui.control.EditField
        inputfilenameEditFieldLabel    matlab.ui.control.Label
        ExecuteButton                  matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        GambarFilterFrekuensiLabel     matlab.ui.control.Label
        Image4                         matlab.ui.control.Image
        ResultImageLabel               matlab.ui.control.Label
        Image3                         matlab.ui.control.Image
        SaltPepperLabel                matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        OriginalImageLabel             matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: ExecuteButton, JenisRanahListBox
        function ExecuteButtonPushed(app, event)

            imgOri = getImg(app.inputfilenameEditField.Value);
            app.Image.ImageSource = imgOri;
%             imgOut=imgOri;
            jenisRanah = app.JenisRanahListBox.Value;
            if jenisRanah =="S" %1 = spasial, 2 = frekuensi
%                 app.Image4.ImageSource = imgOri;
                jenisFilter = app.JenisFilterListBox.Value;
                %M = mean filter, G = gaussian filter, Md = median filter

                if jenisFilter =="Md" %untuk median filter
                    % diberi saltpepper terlebih dahulu
                    imgOri = imnoise(imgOri, 'salt & pepper', 0.1);
                    app.Image2.ImageSource = imgOri;
                end

%                 imgOut = imgOri;
                nMask = app.nuntukFilterEditField.Value; %n harus ganjil
                if nMask <1
                    nMask = 1; %menjadikan minimum n = 1
                end
                if nMask>100
                    nMask = 99; % max n = 99
                end

                imgOut = convolMan (imgOri,nMask,jenisFilter);
                app.Image3.ImageSource = imgOut;
            end
            
            if jenisRanah=="F"
                %ranah frekuensi
                jenisFilter = app.JenisFilterListBox_2.Value;
                %I = Ideal Low Pass Filter (ILPF)
                %G = Gaussian Low Pass Filter (GLPF)
                %B = Butterworth Pass Filter (BLPF)

                jenisPassing = app.JenisPassingListBox.Value;
                %L = low pass, H = high pass
                D0 = app.inputD0EditField.Value;
                n = app.inputnEditField;
                imgOut = imgOri;
                dim =1;
                if ndims(imgOri)==3
                    dim = 3;
                end
                
                for i=1:dim
                    imgOut(:,:,dim) = transformFreq(imgOri(:,:,dim),D0,n,jenisFilter,jenisPassing);
                end

                app.Image3.ImageSource = imgOut;
            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {616, 616};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {367, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ExecuteButton = uibutton(app.LeftPanel, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.inputfilenameEditFieldLabel = uilabel(app.LeftPanel);
            app.inputfilenameEditFieldLabel.Text = 'input filename : ';
            app.inputfilenameEditField = uieditfield(app.LeftPanel, 'text');
            app.inputfilenameEditField.Value = 'flower.png';
            app.penambahanSaltPepperLabel = uilabel(app.LeftPanel);
            app.penambahanSaltPepperLabel.Text = ' penambahan Salt & Pepper';
            app.MedianFilterdenganLabel = uilabel(app.LeftPanel);
            app.MedianFilterdenganLabel.Text = '*Median Filter dengan';
            app.nuntukFilterEditFieldLabel = uilabel(app.LeftPanel);
            app.nuntukFilterEditFieldLabel.Text = 'n untuk Filter';
            app.nuntukFilterEditField = uieditfield(app.LeftPanel, 'numeric');
            app.nuntukFilterEditField.Value = 3;
            app.inputD0EditFieldLabel = uilabel(app.LeftPanel);
            app.inputD0EditFieldLabel.Text = 'input D0';
            app.inputD0EditField = uieditfield(app.LeftPanel, 'numeric');
            app.inputD0EditField.Value = 50;
            app.inputnEditFieldLabel = uilabel(app.LeftPanel);
            app.inputnEditFieldLabel.Text = 'input n';
            app.inputnEditField = uieditfield(app.LeftPanel, 'numeric');
            app.inputnEditField.Value = 2;
            app.ndigunakanhanyapadaBLPFLabel = uilabel(app.LeftPanel);
            app.ndigunakanhanyapadaBLPFLabel.Text = 'n digunakan hanya pada BLPF';
            app.JenisFilterListBoxLabel = uilabel(app.LeftPanel);
            app.JenisFilterListBoxLabel.Text = 'JenisFilter';
            app.JenisFilterListBox = uilistbox(app.LeftPanel);
            app.JenisFilterListBox.Items = {'MeanFilter', 'GaussianFilter', 'MedianFilter'};
            app.JenisFilterListBox.ItemsData = {'M', 'G', 'Md'};
            app.JenisFilterListBox.Value = 'M';
            app.JenisRanahListBoxLabel = uilabel(app.LeftPanel);
            app.JenisRanahListBoxLabel.Text = 'JenisRanah';
            app.JenisRanahListBox = uilistbox(app.LeftPanel);
            app.JenisRanahListBox.Items = {'Spasial', 'Frekuensi'};
            app.JenisRanahListBox.ItemsData = {'S', 'F'};
            app.JenisRanahListBox.ValueChangedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.JenisRanahListBox.Value = 'S';
            app.JenisFilterListBox_2Label = uilabel(app.LeftPanel);
            app.JenisFilterListBox_2Label.Text = 'JenisFilter';
            app.JenisFilterListBox_2 = uilistbox(app.LeftPanel);
            app.JenisFilterListBox_2.Items = {'Ideal Low Pass Filter (ILPF)', 'Gaussian Low Pass Filter (GLPF)', 'Butterworth Pass Filter (BLPF)'};
            app.JenisFilterListBox_2.ItemsData = {'I', 'G', 'B'};
            app.JenisFilterListBox_2.Value = 'I';
            app.JenisPassingListBoxLabel = uilabel(app.LeftPanel);
            app.JenisPassingListBoxLabel.Text = 'JenisPassing';
            app.JenisPassingListBox = uilistbox(app.LeftPanel);
            app.JenisPassingListBox.Items = {'LowPass', 'HighPass'};
            app.JenisPassingListBox.ItemsData = {'L', 'H'};
            app.JenisPassingListBox.Value = 'L';
            app.TransformasiRanahFrekuensiLabel = uilabel(app.LeftPanel);
            app.TransformasiRanahFrekuensiLabel.Text = 'Transformasi Ranah Frekuensi';
            app.TransformasiRanahSpasialLabel = uilabel(app.LeftPanel);
            app.TransformasiRanahSpasialLabel.Text = 'Transformasi Ranah Spasial';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.OriginalImageLabel = uilabel(app.RightPanel);
            app.OriginalImageLabel.Text = 'Original Image';
            app.Image2 = uiimage(app.RightPanel);
            app.SaltPepperLabel = uilabel(app.RightPanel);
            app.SaltPepperLabel.Text = 'Salt & Pepper';
            app.Image3 = uiimage(app.RightPanel);
            app.ResultImageLabel = uilabel(app.RightPanel);
            app.ResultImageLabel.Text = 'Result Image';
            app.Image4 = uiimage(app.RightPanel);
            app.GambarFilterFrekuensiLabel = uilabel(app.RightPanel);
            app.GambarFilterFrekuensiLabel.Text = 'Gambar Filter Frekuensi';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_tucil2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end